classdef RamanPreprocessing_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Mode                            matlab.ui.container.TabGroup
        PreprocessingTab                matlab.ui.container.Tab
        ShowAllSpectraCheckBox          matlab.ui.control.CheckBox
        SelectedPeakField               matlab.ui.control.NumericEditField
        SelectedpeakEditFieldLabel      matlab.ui.control.Label
        NormalisationMethodDropDown     matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        ZapEndField                     matlab.ui.control.NumericEditField
        ZapendEditFieldLabel            matlab.ui.control.Label
        ZapStartField                   matlab.ui.control.NumericEditField
        ZapstartEditFieldLabel          matlab.ui.control.Label
        EndWavenumberField              matlab.ui.control.NumericEditField
        EndwavenumberFieldLabel         matlab.ui.control.Label
        StartWavenumberField            matlab.ui.control.NumericEditField
        StartwavenumberEditFieldLabel   matlab.ui.control.Label
        WindowLengthField               matlab.ui.control.NumericEditField
        WindowlengthEditFieldLabel      matlab.ui.control.Label
        PolyOrderField                  matlab.ui.control.NumericEditField
        PolynomialorderEditFieldLabel   matlab.ui.control.Label
        Iterations2Field                matlab.ui.control.NumericEditField
        Iterations2EditFieldLabel       matlab.ui.control.Label
        Bandwidth2Field                 matlab.ui.control.NumericEditField
        Bandwidth2EditFieldLabel        matlab.ui.control.Label
        Smooth2Field                    matlab.ui.control.NumericEditField
        Smooth2EditFieldLabel           matlab.ui.control.Label
        Iterations1Field                matlab.ui.control.NumericEditField
        Iterations1EditFieldLabel       matlab.ui.control.Label
        Bandwidth1Field                 matlab.ui.control.NumericEditField
        Bandwidth1EditFieldLabel        matlab.ui.control.Label
        Smooth1Field                    matlab.ui.control.NumericEditField
        Smooth1EditFieldLabel           matlab.ui.control.Label
        ReferencePeakField              matlab.ui.control.NumericEditField
        Referencepeakcm1EditFieldLabel  matlab.ui.control.Label
        SpectrumNumberField             matlab.ui.control.NumericEditField
        SpectrumNumberFieldLabel        matlab.ui.control.Label
        ClassDropDown                   matlab.ui.control.DropDown
        ClassDropDownLabel              matlab.ui.control.Label
        Progress                        matlab.ui.control.Label
        Save                            matlab.ui.control.Button
        ProcessAllButton                matlab.ui.control.Button
        NormalisationLabel              matlab.ui.control.Label
        EnableZappingCheckBox           matlab.ui.control.CheckBox
        TruncatingandZappingLabel       matlab.ui.control.Label
        SmoothingLabel                  matlab.ui.control.Label
        BaselinesubtractionLabel        matlab.ui.control.Label
        MeasuredPeakLabel               matlab.ui.control.Label
        ClassInfoLabel                  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        LDAAccuracyLabel                matlab.ui.control.Label
        ConfusionMatrixDropDown         matlab.ui.control.DropDown
        ConfusionmatrixdropdownLabel    matlab.ui.control.Label
        LDALoadingDropDown              matlab.ui.control.DropDown
        LDALoadingDropDownLabel         matlab.ui.control.Label
        RunPCALDAButton                 matlab.ui.control.Button
        RandomSeedField                 matlab.ui.control.NumericEditField
        RandomseedEditFieldLabel        matlab.ui.control.Label
        KFoldField                      matlab.ui.control.NumericEditField
        kvalueforkfoldLabel             matlab.ui.control.Label
        SupervisedLabel                 matlab.ui.control.Label
        RunPCAUMAPButton                matlab.ui.control.Button
        RunUMAPButton                   matlab.ui.control.Button
        DistancemetricDropDown          matlab.ui.control.DropDown
        DistancemetricDropDownLabel     matlab.ui.control.Label
        NumberOfNeighboursField         matlab.ui.control.NumericEditField
        NumberOfNeighboursFieldLabel    matlab.ui.control.Label
        UMAPLabel                       matlab.ui.control.Label
        UnsupervisedLabel               matlab.ui.control.Label
        LDALabel                        matlab.ui.control.Label
        LoadPreprocessedButton          matlab.ui.control.Button
        RunPCAButton                    matlab.ui.control.Button
        ScoreYDropDown                  matlab.ui.control.DropDown
        ScoreYDropDownLabel             matlab.ui.control.Label
        ScoreXDropDown                  matlab.ui.control.DropDown
        ScoreXDropDownLabel             matlab.ui.control.Label
        PCALoadingDropDown              matlab.ui.control.DropDown
        LoadingDropDownLabel            matlab.ui.control.Label
        PCAVarianceField                matlab.ui.control.NumericEditField
        VarianceExplainedFieldLabel     matlab.ui.control.Label
        PCAsettingsLabel                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RawTab                          matlab.ui.container.Tab
        UIAxesRaw                       matlab.ui.control.UIAxes
        AlignmentTab                    matlab.ui.container.Tab
        UIAxesAlignment                 matlab.ui.control.UIAxes
        BackgroundsubtractionTab        matlab.ui.container.Tab
        UIAxesEMSC                      matlab.ui.control.UIAxes
        BaselineTab                     matlab.ui.container.Tab
        UIAxesBaseline                  matlab.ui.control.UIAxes
        SmoothingTab                    matlab.ui.container.Tab
        UIAxesSmooth                    matlab.ui.control.UIAxes
        TruncationandZappingTab         matlab.ui.container.Tab
        UIAxesTrunc                     matlab.ui.control.UIAxes
        NormalisationTab                matlab.ui.container.Tab
        UIAxesNormAfter                 matlab.ui.control.UIAxes
        UIAxesNormBefore                matlab.ui.control.UIAxes
        PCATab                          matlab.ui.container.Tab
        UIAxesPCAScores                 matlab.ui.control.UIAxes
        UIAxesPCALoading                matlab.ui.control.UIAxes
        UIAxesVarianceExplained         matlab.ui.control.UIAxes
        UMAPTab                         matlab.ui.container.Tab
        UIAxesUMAP                      matlab.ui.control.UIAxes
        LDATab                          matlab.ui.container.Tab
        UIAxesLDALoading                matlab.ui.control.UIAxes
        UIAxesLDAScores                 matlab.ui.control.UIAxes
        LDAConfusionmatrixTab           matlab.ui.container.Tab
        ConfusionPanel                  matlab.ui.container.Panel
    end

    properties (Access = private)
        RamanSpectra
        RamanBackgroundSpectra
        CurrentClass
        CurrentSpectrumIndex
        Xintvalues
        Yaligned
        BgInterpolated
        BgContribution
        Yemsc
        Ybaseline
        BaselineFit
        Ybaseline1
        BaselineFit1
        Ysmooth
        Xtrunc
        Ytrunc
        XZap
        YZap
        Ynorm
        ProcessedDataMatrix
        ProcessedClasses
        ProcessedWavenumbers
        ProcessedData
        BeforeNorm
        ProcessingValid = false
        ProcessedDataLoadedOnly = false;
        AxesLimBefore
        AxesLimAfter
        PCAResults
        LDAResults
        UMAPResults
    end
    
    methods (Access = private)
        function InvalidateProcessedData(app)
            if app.ProcessedDataLoadedOnly
                Selection = uiconfirm(app.UIFigure,...
                    ['Changing preprocessing parameters will ' ...
                    'discard the loaded processed dataset.' ...
                    newline newline ...
                    'Continue?'],...
                    'Warning',...
                    'Options',{'Continue','Cancel'});
                if strcmp(Selection,'Cancel')
                    return
                end
                app.ProcessedDataLoadedOnly = false;
            end
            app.ProcessingValid = false;
            % These lines will be added later as the
            % controls are created
            app.ShowAllSpectraCheckBox.Value = false;
            app.ShowAllSpectraCheckBox.Enable = 'off';
            app.ShowAllSpectraCheckBox.Visible = 'off';
            % app.AverageTab.Visible = 'off';
            % app.AnalysisTab.Enable = 'off';
        end

        function PlotCurrentSpectrum(app)
            if isempty(app.RamanSpectra)
                Selection = uiconfirm(app.UIFigure,...
                    ['The original raw dataset is required to view ' ...
                    'individual spectra and preprocessing steps.' ...
                    newline newline ...
                    'Would you like to load the raw dataset now?'],...
                    'Raw data required',...
                    'Options',{'Load raw data','Cancel'});
                if strcmp(Selection,'Load raw data')
                    LoadButtonPushed(app,[]);
                end
                return
            end
            if app.ProcessingValid && app.ShowAllSpectraCheckBox.Value
                PlotProcessedClass(app)
                return
            end

            Matches = strcmp(app.RamanSpectra(:,2), app.CurrentClass);      
            Indices = find(Matches);
            app.CurrentSpectrumIndex = ...
                Indices(app.SpectrumNumberField.Value);
            UpdateProcessing(app);
            Spectrum = app.RamanSpectra{app.CurrentSpectrumIndex,1};
            MeasuredPeak = app.RamanSpectra{app.CurrentSpectrumIndex,3};
            app.MeasuredPeakLabel.Text = ...
                sprintf('Measured peak: %.3f cm^-1',MeasuredPeak);

            % Raw spectrum
            plot(app.UIAxesRaw,...
                Spectrum(:,1),...
                Spectrum(:,2))
            axis(app.UIAxesRaw,'tight')

            % Aligned spectrum
            plot(app.UIAxesAlignment,...
                Spectrum(:,1),...
                Spectrum(:,2))
            hold(app.UIAxesAlignment,'on')
            plot(app.UIAxesAlignment,...
                app.Xintvalues,...
                app.Yaligned,'r')
            hold(app.UIAxesAlignment,'off')
            axis(app.UIAxesAlignment,'tight')

            % EMSC
            plot(app.UIAxesEMSC,...
                app.Xintvalues,...
                app.Yaligned,'k')
            hold(app.UIAxesEMSC,'on')
            plot(app.UIAxesEMSC,...
                app.Xintvalues,...
                app.BgContribution,'b')
            plot(app.UIAxesEMSC,...
                app.Xintvalues,...
                app.Yemsc,'r')
            hold(app.UIAxesEMSC,'off')
            axis(app.UIAxesEMSC,'tight')
            legend(app.UIAxesEMSC,...
                {'Before','Background','Corrected'})

            % Baseline correction
            plot(app.UIAxesBaseline,...
                app.Xintvalues,...
                app.Yemsc,'k')
            hold(app.UIAxesBaseline,'on')
            plot(app.UIAxesBaseline,...
                app.Xintvalues,...
                app.BaselineFit,'b')
            plot(app.UIAxesBaseline,...
                app.Xintvalues,...
                app.Ybaseline,'r')
            yline(app.UIAxesBaseline,0,'--k');
            hold(app.UIAxesBaseline,'off')
            axis(app.UIAxesBaseline,'tight')
            legend(app.UIAxesBaseline,...
                {'Before baseline correction',...
                'Baseline',...
                'Corrected spectrum'})

            % Smoothing
            plot(app.UIAxesSmooth,...
                app.Xintvalues,...
                app.Ybaseline,'k')
            hold(app.UIAxesSmooth,'on')
            plot(app.UIAxesSmooth,...
                app.Xintvalues,...
                app.Ysmooth,'r')      
            hold(app.UIAxesSmooth,'off')
            axis(app.UIAxesSmooth,'tight')
            legend(app.UIAxesSmooth,...
                {'Before smoothing',...
                'Smoothed spectrum'})

            % Truncation and Zapping
            h1=plot(app.UIAxesTrunc,...
                app.Xintvalues,...
                app.Ysmooth,'k');
            hold(app.UIAxesTrunc,'on')
            xline(app.UIAxesTrunc,...
                app.StartWavenumberField.Value,'g')
            xline(app.UIAxesTrunc,...
                app.EndWavenumberField.Value,'g')
            if app.EnableZappingCheckBox.Value
                xline(app.UIAxesTrunc,...
                    app.ZapStartField.Value,...
                    'r--','Zap start');
                xline(app.UIAxesTrunc,...
                    app.ZapEndField.Value,...
                    'r--','Zap end');
            end
            h2=plot(app.UIAxesTrunc,...
                app.XZap,...
                app.YZap,'b');
            hold(app.UIAxesTrunc,'off')
            axis(app.UIAxesTrunc,'tight')
            if app.EnableZappingCheckBox.Value
            legend(app.UIAxesTrunc,...
                [h1 h2],...
                {'Smoothed spectrum',...
                'Truncated and Zapped spectrum'})
            else
            legend(app.UIAxesTrunc,...
                [h1 h2],...
                {'Smoothed spectrum',...
                'Truncated spectrum'})
            end

            % Normalisation
            cla(app.UIAxesNormBefore)
            cla(app.UIAxesNormAfter)
            plot(app.UIAxesNormBefore,app.XZap,app.YZap)
            plot(app.UIAxesNormAfter,app.XZap,app.Ynorm)
            if ~app.ProcessingValid
                axis(app.UIAxesNormBefore,'tight')
                axis(app.UIAxesNormAfter,'tight')
            else
                ylim(app.UIAxesNormBefore,app.AxesLimBefore);
                ylim(app.UIAxesNormAfter,app.AxesLimAfter);    
            end
        end
        
        function UpdateProcessing(app)
            Spectrum = app.RamanSpectra{app.CurrentSpectrumIndex,1};
            MeasuredPeak = app.RamanSpectra{app.CurrentSpectrumIndex,3};
            % Alignment
            [app.Yaligned,app.Xintvalues] = ...
                AlignSpectrum(Spectrum,MeasuredPeak,app.ReferencePeakField.Value);

            % BackgroundSubtraction (EMSC)
            [~,app.Yemsc,app.BgContribution] = ...
                emsc(app.Yaligned,cell2mat(app.BgInterpolated));

            % Baseline correction
            [app.Ybaseline1,app.BaselineFit1] = ...
                f_baseline_corr( ...
                app.Xintvalues,...
                app.Yemsc,...
                app.Smooth1Field.Value,...
                app.Bandwidth1Field.Value,...
                app.Iterations1Field.Value);

            [app.Ybaseline,app.BaselineFit] = ...
                f_baseline_corr( ...
                app.Xintvalues,...
                app.Ybaseline1,...
                app.Smooth2Field.Value,...
                app.Bandwidth2Field.Value,...
                app.Iterations2Field.Value);

            % Smoothing
            Order = app.PolyOrderField.Value;
            Window = round(app.WindowLengthField.Value); % Window is an integrer
            if mod(Window,2)==0
                Window = Window + 1; % Window is an odd number
            end
            if Window <= Order
                if mod(Order,2)==0
                    Window = Order + 1;
                else
                    Window = Order + 2;
                end
            end
            app.WindowLengthField.Value = Window;
            app.Ysmooth = sgolayfilt( ...
                app.Ybaseline,...
                app.PolyOrderField.Value,...
                app.WindowLengthField.Value);   

            % Truncation and Zapping
            Index = app.Xintvalues >= app.StartWavenumberField.Value & ...
                    app.Xintvalues <= app.EndWavenumberField.Value;
            app.Xtrunc = app.Xintvalues(Index);
            app.Ytrunc = app.Ysmooth(Index);
            if app.EnableZappingCheckBox.Value % Optional Zapping
                Index = ~(app.Xtrunc > app.ZapStartField.Value & ...
                    app.Xtrunc < app.ZapEndField.Value);
                app.XZap = app.Xtrunc(Index);
                app.YZap = app.Ytrunc(Index);
            else
                app.XZap = app.Xtrunc;
                app.YZap = app.Ytrunc;
            end

            % Normalisation
            switch app.NormalisationMethodDropDown.Value
            case 'Amide I peak'        
                x1 = find(round(app.XZap)==1630,1);
                x2 = find(round(app.XZap)==1680,1);
                NormFactor = max( ...
                    sgolayfilt(app.YZap(x1:x2),2,31));
            case 'Total area'
                NormFactor = trapz( ...
                    app.XZap,...
                    app.YZap);
            case 'Selected peak'
                PeakPos = app.SelectedPeakField.Value;
                [~,PeakIndex] = min(abs(app.XZap-PeakPos));
                PeakPos = app.XZap(PeakIndex);
                app.SelectedPeakField.Value = PeakPos;
                NormFactor = app.YZap(PeakIndex);
            end
            if NormFactor == 0
                NormFactor = 1;
            end
            app.Ynorm = app.YZap ./ NormFactor;
        end
    
        function PlotProcessedClass(app)
            Matches = strcmp(app.ProcessedClasses,app.CurrentClass);
            Indices = find(Matches);
            cla(app.UIAxesNormAfter)
            cla(app.UIAxesNormBefore)
            hold(app.UIAxesNormAfter,'on')
            hold(app.UIAxesNormBefore,'on')
            for k = 1:length(Indices)
                plot(app.UIAxesNormAfter,app.ProcessedWavenumbers,...
                    app.ProcessedDataMatrix(:,Indices(k)))
                plot(app.UIAxesNormBefore,app.ProcessedWavenumbers,...
                    app.BeforeNorm(:,Indices(k)))
            end
            hold(app.UIAxesNormAfter,'off')
            hold(app.UIAxesNormBefore,'off')
            axis(app.UIAxesNormAfter,'tight')
            axis(app.UIAxesNormBefore,'tight')
            app.AxesLimBefore = ylim(app.UIAxesNormBefore);
            app.AxesLimAfter  = ylim(app.UIAxesNormAfter);
        end
        
        function UpdatePCAScorePlot(app)
            PCX = sscanf( ...
                app.ScoreXDropDown.Value,...
                'PC%d');
            PCY = sscanf( ...
                app.ScoreYDropDown.Value,...
                'PC%d');
            PlotPCAScores( ...
                app.UIAxesPCAScores,...
                app.PCAResults,...
                app.ProcessedClasses,...
                unique(app.ProcessedClasses),...
                PCX,...
                PCY);
        end
        
        function PlotUMAP(app)
            cla(app.UIAxesUMAP)
            Classes = unique(app.ProcessedClasses);
            hold(app.UIAxesUMAP,'on')
            for k = 1:length(Classes)
                idx = strcmp(app.ProcessedClasses,...
                    Classes{k});
                scatter(app.UIAxesUMAP,app.UMAPResults.Embedding(idx,1),...
                    app.UMAPResults.Embedding(idx,2),40,'filled')
            end
            hold(app.UIAxesUMAP,'off')
            LegendText = cell(size(Classes));
            for k = 1:length(Classes)
                idx = strcmp(app.ProcessedClasses,Classes{k});
                LegendText{k} = sprintf('%s (N=%d)',Classes{k},sum(idx));
            end
            legend(app.UIAxesUMAP,LegendText)
            xlabel(app.UIAxesUMAP,'UMAP 1')
            ylabel(app.UIAxesUMAP,'UMAP 2')
        end
        
        function UpdateLDAScorePlot(app)
            LD = sscanf(app.LDALoadingDropDown.Value,'LD%d -');       
            Fold = app.LDAResults.KFolds;
            TestIdx = app.LDAResults.TestIndices{Fold};
            cla(app.UIAxesLDAScores)
            Classes = unique(app.ProcessedClasses);
            hold(app.UIAxesLDAScores,'on')
            LegendText = cell(length(Classes),1);
            for k = 1:length(Classes)
                idx = strcmp(app.ProcessedClasses,Classes{k}) & TestIdx;
                histogram(app.UIAxesLDAScores,app.LDAResults.Scores(idx,LD),...
                    'Normalization','probability',...
                    'NumBins',20,'FaceAlpha',0.5,'EdgeAlpha',0.7);
                LegendText{k} = sprintf('%s (N=%d)',Classes{k},sum(idx));
            end
            hold(app.UIAxesLDAScores,'off')
            legend(app.UIAxesLDAScores,LegendText,'Location','eastoutside')
            xlabel(app.UIAxesLDAScores,sprintf('LD%d score',LD))
            ylabel(app.UIAxesLDAScores,'Probability')
            title(app.UIAxesLDAScores,sprintf('%s (Validation Fold %d)',...
                app.LDAResults.LDNames{LD},Fold))
        end
        
        function PlotLDALoading(app)
            LoadingName = app.LDALoadingDropDown.Value;
            idx = find(strcmp(app.LDAResults.LDNames,LoadingName));
            plot(app.UIAxesLDALoading,app.ProcessedWavenumbers,...
                app.LDAResults.Loadings(:,idx));
            xlabel(app.UIAxesLDALoading,'Wavenumber (cm^-1)')
            ylabel(app.UIAxesLDALoading,'LDA loading')
            title(app.UIAxesLDALoading,LoadingName)
        end
        
        function UpdateConfusionMatrix(app)
            if ~isempty(app.ConfusionPanel.Children)
                delete(app.ConfusionPanel.Children)
            end
            Selection = app.ConfusionMatrixDropDown.Value;
            if strcmp(Selection,'Overall')
                Matrix = round(app.LDAResults.MeanConfusionMatrix);
            else
                FoldNumber = sscanf(Selection,'Fold %d');
                Matrix = app.LDAResults.FoldConfusionMatrices{FoldNumber};
            end
            confusionchart(app.ConfusionPanel,Matrix,app.LDAResults.ClassNames);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');

            if isequal(file,0)
                return
            end

            S = load(fullfile(path,file));
            
            app.RamanSpectra = S.RamanSpectra;
            app.RamanBackgroundSpectra = S.RamanBackgroundSpectra;
            app.BgInterpolated = cell(1,size(app.RamanBackgroundSpectra,1));
            for k = 1:size(app.RamanBackgroundSpectra,1)
                MeasuredPeak = app.RamanBackgroundSpectra{k,3};
                [app.BgInterpolated{k},~] = ...
                    AlignSpectrum( ...
                    app.RamanBackgroundSpectra{k,1}, ...
                    MeasuredPeak, ...
                    app.ReferencePeakField.Value);
            end
            app.ClassDropDown.Items = unique(app.RamanSpectra(:,2));
            app.CurrentClass = app.ClassDropDown.Value;
            ClassDropDownValueChanged(app,[]);
            if ~isempty(app.Xintvalues)
                app.StartWavenumberField.Value = app.Xintvalues(1);
                app.EndWavenumberField.Value = app.Xintvalues(end);
            end
            app.TabGroup.SelectedTab = app.RawTab;
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            app.CurrentClass = app.ClassDropDown.Value;
            Matches = strcmp(app.RamanSpectra(:,2), app.CurrentClass);
            N = sum(Matches);
            app.ClassInfoLabel.Text = ...
                ['Found ' num2str(N) ' spectra in class ' app.CurrentClass];
            app.SpectrumNumberField.Limits = [1 N];
            app.SpectrumNumberField.Value = 1;
            PlotCurrentSpectrum(app)
        end

        % Value changed function: SpectrumNumberField
        function SpectrumNumberFieldValueChanged(app, event)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: ReferencePeakField
        function ReferencePeakFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Iterations2Field
        function Iterations2FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Bandwidth2Field
        function Bandwidth2FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Smooth2Field
        function Smooth2FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Iterations1Field
        function Iterations1FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Bandwidth1Field
        function Bandwidth1FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: Smooth1Field
        function Smooth1FieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: PolyOrderField
        function PolyOrderFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: WindowLengthField
        function WindowLengthFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: StartWavenumberField
        function StartWavenumberFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: EndWavenumberField
        function EndWavenumberFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: ZapStartField
        function ZapStartFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: ZapEndField
        function ZapEndFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: EnableZappingCheckBox
        function EnableZappingCheckBoxValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Value changed function: NormalisationMethodDropDown
        function NormalisationMethodDropDownValueChanged(app, event)
            InvalidateProcessedData(app)
            if strcmp(app.NormalisationMethodDropDown.Value,...
                    'Selected peak')
                app.SelectedPeakField.Visible = 'on';
                app.SelectedpeakEditFieldLabel.Visible = 'on';
            else
                app.SelectedPeakField.Visible = 'off';
                app.SelectedpeakEditFieldLabel.Visible = 'off';
            end
            PlotCurrentSpectrum(app)
        end

        % Value changed function: SelectedPeakField
        function SelectedPeakFieldValueChanged(app, event)
            InvalidateProcessedData(app)
            PlotCurrentSpectrum(app)
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            N = size(app.RamanSpectra,1);
            app.ProcessedDataMatrix = [];
            app.ProcessedClasses = app.RamanSpectra(:,2);
            for k = 1:N
                app.CurrentSpectrumIndex = k;
                UpdateProcessing(app);
                if mod(k,ceil(N/10)) == 0 || k == N
                    Percent = round(100*k/N);
                    app.Progress.Text= ...
                        sprintf('Processing: %d%%',Percent);
                    drawnow limitrate
                end
                if k == 1
                    app.ProcessedWavenumbers = app.XZap;
                    app.ProcessedDataMatrix = ...
                        zeros(length(app.Ynorm),N);
                    app.BeforeNorm = app.ProcessedDataMatrix;
                end
                app.ProcessedDataMatrix(:,k) = app.Ynorm;
                app.BeforeNorm(:,k)=app.YZap;
            end
            % Update the plots
            cla(app.UIAxesNormBefore)
            cla(app.UIAxesNormAfter)
            hold(app.UIAxesNormBefore,'on')
            Matches = strcmp(app.RamanSpectra(:,2), app.CurrentClass);
            Indices = find(Matches);
            for k = Indices
                plot(app.UIAxesNormBefore,...
                    app.XZap,...
                    app.BeforeNorm(:,k))
                plot(app.UIAxesNormAfter,...
                    app.XZap,...
                    app.ProcessedDataMatrix(:,k))
            end
            hold(app.UIAxesNormBefore,'off')
            axis(app.UIAxesNormBefore,'tight')
            hold(app.UIAxesNormAfter,'off')
            axis(app.UIAxesNormAfter,'tight')
            % Store the data
            app.ProcessedData.DataMatrix = app.ProcessedDataMatrix;
            app.ProcessedData.Classes = app.ProcessedClasses;
            app.ProcessedData.Wavenumbers = app.ProcessedWavenumbers;
            app.ProcessedData.BeforeNorm = app.BeforeNorm;
            app.ProcessingValid = true;
            app.ShowAllSpectraCheckBox.Enable = 'on';
            app.ShowAllSpectraCheckBox.Value = true;
            app.ShowAllSpectraCheckBox.Visible = 'on';
            uialert(app.UIFigure,...
                sprintf('%d spectra processed successfully.',N),...
                'Finished')
            % Store information about the processing
            app.ProcessedData.ReferencePeak=app.ReferencePeakField.Value;
            app.ProcessedData.Baseline.Smooth1=app.Smooth1Field.Value;
            app.ProcessedData.Baseline.Bandwidth1=app.Bandwidth1Field.Value;
            app.ProcessedData.Baseline.Iterations1=app.Iterations1Field.Value;
            app.ProcessedData.Baseline.Smooth2=app.Smooth2Field.Value;
            app.ProcessedData.Baseline.Bandwidth2=app.Bandwidth2Field.Value;
            app.ProcessedData.Baseline.Iterations2=app.Iterations2Field.Value;
            app.ProcessedData.Smoothing.PolyOrder=app.PolyOrderField.Value;
            app.ProcessedData.Smoothing.WindowLength=app.WindowLengthField.Value;
            app.ProcessedData.Truncation.StartWavenumber=app.StartWavenumberField.Value;
            app.ProcessedData.Truncation.EndWavenumber=app.EndWavenumberField.Value;
            app.ProcessedData.Zapping.Enabled=app.EnableZappingCheckBox.Value;
            app.ProcessedData.Zapping.Start=app.ZapStartField.Value;
            app.ProcessedData.Zapping.End=app.ZapEndField.Value;
            app.ProcessedData.Normalisation.Method=app.NormalisationMethodDropDown.Value;
            if strcmp(app.NormalisationMethodDropDown.Value,...
                    'Selected peak')
                app.ProcessedData.Normalisation.SelectedPeak = ...
                    app.SelectedPeakField.Value;
            end
            app.ProcessedData.DateProcessed = datetime('now');
            app.TabGroup.SelectedTab = app.NormalisationTab;
            app.ProcessedDataLoadedOnly = false;
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            app.ProcessAllButtonPushed
            [file,path] = uiputfile('ProcessedData.mat');
            if isequal(file,0)
                return
            end
            ProcessedData = app.ProcessedData;
            save(fullfile(path,file),...
                'ProcessedData')
        end

        % Value changed function: ShowAllSpectraCheckBox
        function ShowAllSpectraCheckBoxValueChanged(app, event)
            PlotCurrentSpectrum(app)            
        end

        % Button pushed function: RunPCAButton
        function RunPCAButtonPushed(app, event)
            app.PCAResults=RunPCA(app.ProcessedDataMatrix,...
                app.PCAVarianceField.Value);
            % Populate dropdowns
            NPC = app.PCAResults.NumComponents;
            PCStrings = strcat("PC",string(1:NPC));
            app.PCALoadingDropDown.Items = cellstr(PCStrings);
            app.ScoreXDropDown.Items = cellstr(PCStrings);
            app.ScoreYDropDown.Items = cellstr(PCStrings);
            app.PCALoadingDropDown.Value = 'PC1';
            app.ScoreXDropDown.Value = 'PC1';
            if NPC >= 2
                app.ScoreYDropDown.Value = 'PC2';
            else
                app.ScoreYDropDown.Value = 'PC1';
            end
            PlotPCAVariance( ...
                app.UIAxesVarianceExplained,app.PCAResults,app.PCAVarianceField.Value);
            PlotPCALoading(app.UIAxesPCALoading,app.PCAResults,...
                app.ProcessedWavenumbers,...
                1);
            PlotPCAScores(app.UIAxesPCAScores,app.PCAResults,...
                app.ProcessedClasses,unique(app.ProcessedClasses),1,2);
            app.TabGroup.SelectedTab = app.PCATab;
        end

        % Value changed function: PCALoadingDropDown
        function PCALoadingDropDownValueChanged(app, event)
            PC = sscanf(app.PCALoadingDropDown.Value,'PC%d');
            PlotPCALoading(app.UIAxesPCALoading,app.PCAResults,...
                app.ProcessedWavenumbers,PC);
        end

        % Value changed function: ScoreXDropDown
        function ScoreXDropDownValueChanged(app, event)
            UpdatePCAScorePlot(app);
        end

        % Value changed function: ScoreYDropDown
        function ScoreYDropDownValueChanged(app, event)
            UpdatePCAScorePlot(app);
        end

        % Button pushed function: LoadPreprocessedButton
        function LoadPreprocessedButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                return
            end
            S = load(fullfile(path,file));
            if ~isfield(S,'ProcessedData')
                uialert(app.UIFigure,...
                    'Selected file is not a valid processed dataset.',...
                    'Invalid file')
                return
            end
            app.ProcessedData = S.ProcessedData;
            app.ProcessedDataMatrix = app.ProcessedData.DataMatrix;
            app.ProcessedClasses = app.ProcessedData.Classes;
            app.ProcessedWavenumbers = app.ProcessedData.Wavenumbers;
            app.BeforeNorm = app.ProcessedData.BeforeNorm;
            app.ProcessingValid = true;
            % Update the pre-processing controls
            app.ClassDropDown.Items = unique(app.ProcessedClasses);
            app.ClassDropDown.Value = app.ClassDropDown.Items{1};
            app.CurrentClass = app.ClassDropDown.Value;
            app.ReferencePeakField.Value = app.ProcessedData.ReferencePeak;
            app.Smooth1Field.Value = app.ProcessedData.Baseline.Smooth1;
            app.Bandwidth1Field.Value = app.ProcessedData.Baseline.Bandwidth1;
            app.Iterations1Field.Value = app.ProcessedData.Baseline.Iterations1;
            app.Smooth2Field.Value = app.ProcessedData.Baseline.Smooth2;
            app.Bandwidth2Field.Value = app.ProcessedData.Baseline.Bandwidth2;
            app.Iterations2Field.Value = app.ProcessedData.Baseline.Iterations2;
            app.PolyOrderField.Value = app.ProcessedData.Smoothing.PolyOrder;
            app.WindowLengthField.Value = app.ProcessedData.Smoothing.WindowLength;
            app.StartWavenumberField.Value = app.ProcessedData.Truncation.StartWavenumber;
            app.EndWavenumberField.Value = app.ProcessedData.Truncation.EndWavenumber;
            app.EnableZappingCheckBox.Value = app.ProcessedData.Zapping.Enabled;
            app.ZapStartField.Value = app.ProcessedData.Zapping.Start;
            app.ZapEndField.Value = app.ProcessedData.Zapping.End;
            app.NormalisationMethodDropDown.Value = app.ProcessedData.Normalisation.Method;
            if strcmp(app.ProcessedData.Normalisation.Method,'Selected peak')
                app.SelectedPeakField.Visible = 'on';
                app.SelectedpeakEditFieldLabel.Visible = 'on';
                app.SelectedPeakField.Value = ...
                    app.ProcessedData.Normalisation.SelectedPeak;
            else
                app.SelectedPeakField.Visible = 'off';
                app.SelectedpeakEditFieldLabel.Visible = 'off';
            end
            uialert(app.UIFigure,...
                'Preprocessed dataset loaded successfully.',...
                'Success')
            app.ShowAllSpectraCheckBox.Visible = 'on';
            app.ShowAllSpectraCheckBox.Enable = 'on';
            app.ShowAllSpectraCheckBox.Value = true;
            PlotCurrentSpectrum(app)
            app.TabGroup.SelectedTab = app.NormalisationTab;
            app.ProcessedDataLoadedOnly = true;
        end

        % Button pushed function: RunUMAPButton
        function RunUMAPButtonPushed(app, event)
            if isempty(app.ProcessedDataMatrix)
                uialert(app.UIFigure,...
                    'No processed dataset available.',...
                    'UMAP');
                return
            end
            N = size(app.ProcessedDataMatrix,2);
            MaxNeighbours = N - 1;
            if app.NumberOfNeighboursField.Value > MaxNeighbours
                app.NumberOfNeighboursField.Value = MaxNeighbours;
            end
            app.UMAPResults = RunUMAP( ...
                app.ProcessedDataMatrix',...
                app.NumberOfNeighboursField.Value,...
                app.DistancemetricDropDown.Value);
            PlotUMAP(app)
            app.TabGroup.SelectedTab = app.UMAPTab;
        end

        % Button pushed function: RunPCAUMAPButton
        function RunPCAUMAPButtonPushed(app, event)
            if isempty(app.ProcessedDataMatrix)
                uialert(app.UIFigure,...
                    'No processed dataset available.',...
                    'PCA + UMAP');
                return
            end
            N = size(app.ProcessedDataMatrix,2);
            MaxNeighbours = N - 1;
            if app.NumberOfNeighboursField.Value > MaxNeighbours
                app.NumberOfNeighboursField.Value = MaxNeighbours;
            end
            if isempty(app.PCAResults)
                app.PCAResults = RunPCA(app.ProcessedDataMatrix,...
                    app.PCAVarianceField.Value);
            end
            app.UMAPResults = RunUMAP( ...
                app.PCAResults.score(:,1:app.PCAResults.NumComponents),...
                app.NumberOfNeighboursField.Value,...
                app.DistancemetricDropDown.Value);
            PlotUMAP(app)
            app.TabGroup.SelectedTab = app.UMAPTab;
        end

        % Button pushed function: RunPCALDAButton
        function RunPCALDAButtonPushed(app, event)
            if isempty(app.ProcessedDataMatrix)
                uialert(app.UIFigure,'No processed dataset available.',...
                    'PCA + LDA');
                return
            end
            app.LDAResults = RunLDA(app.ProcessedDataMatrix,...
                app.ProcessedClasses,app.PCAVarianceField.Value,...
                app.KFoldField.Value,app.RandomSeedField.Value);
            NLD = size(app.LDAResults.LDNames,2);
            % Dropdowns
            app.LDALoadingDropDown.Items = app.LDAResults.LDNames;
            app.LDALoadingDropDown.Value = app.LDAResults.LDNames{1};
            UpdateLDAScorePlot(app)
            PlotLDALoading(app)
            Items = {'Overall'};
            for k = 1:app.LDAResults.KFolds
                Items{end+1} = sprintf('Fold %d',k);
            end
            app.LDALoadingDropDownLabel.Visible = 'on';
            app.LDALoadingDropDown.Visible = 'on';
            app.ConfusionMatrixDropDown.Items = Items;
            app.ConfusionMatrixDropDown.Value = 'Overall';
            app.ConfusionmatrixdropdownLabel.Visible = 'on';
            app.ConfusionMatrixDropDown.Visible = 'on';
            UpdateConfusionMatrix(app)
            app.LDAAccuracyLabel.Text = sprintf( ...
                'CV accuracy: %.1f ± %.1f %%', ...
                100*app.LDAResults.MeanFoldAccuracy,...
                100*app.LDAResults.SemFoldAccuracy);
            app.LDAAccuracyLabel.Visible="on";
            app.TabGroup.SelectedTab = app.LDATab;
        end

        % Value changed function: ConfusionMatrixDropDown
        function ConfusionMatrixDropDownValueChanged(app, event)
            Selection = app.ConfusionMatrixDropDown.Value;
            if strcmp(Selection,'Overall')
                Matrix = app.LDAResults.MeanConfusionMatrix; % in percentages
            else
                FoldNumber = sscanf(Selection,'Fold %d');
                Matrix = app.LDAResults.FoldConfusionMatrices{FoldNumber};
            end
            UpdateConfusionMatrix(app)
            app.TabGroup.SelectedTab = app.LDAConfusionmatrixTab;
        end

        % Value changed function: LDALoadingDropDown
        function LDALoadingDropDownValueChanged(app, event)
            PlotLDALoading(app)
            UpdateLDAScorePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RawTab = uitab(app.TabGroup);
            app.RawTab.Title = 'Raw';
            app.UIAxesRaw = uiaxes(app.RawTab);
            title(app.UIAxesRaw, 'Raw')
            xlabel(app.UIAxesRaw, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesRaw, 'Raman Intensity (counts)')
            zlabel(app.UIAxesRaw, 'Z')
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.UIAxesAlignment = uiaxes(app.AlignmentTab);
            title(app.UIAxesAlignment, 'Alignment')
            xlabel(app.UIAxesAlignment, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesAlignment, 'Raman Intensity (counts)')
            zlabel(app.UIAxesAlignment, 'Z')
            app.BackgroundsubtractionTab = uitab(app.TabGroup);
            app.BackgroundsubtractionTab.Title = 'Background subtraction';
            app.UIAxesEMSC = uiaxes(app.BackgroundsubtractionTab);
            title(app.UIAxesEMSC, 'Background subtraction (EMSC)')
            xlabel(app.UIAxesEMSC, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesEMSC, 'Raman intensity (counts)')
            zlabel(app.UIAxesEMSC, 'Z')
            app.BaselineTab = uitab(app.TabGroup);
            app.BaselineTab.Title = 'Baseline';
            app.UIAxesBaseline = uiaxes(app.BaselineTab);
            title(app.UIAxesBaseline, 'Baseline subtraction')
            xlabel(app.UIAxesBaseline, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesBaseline, 'Raman intensity (counts)')
            zlabel(app.UIAxesBaseline, 'Z')
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.UIAxesSmooth = uiaxes(app.SmoothingTab);
            title(app.UIAxesSmooth, 'Smoothing')
            xlabel(app.UIAxesSmooth, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesSmooth, 'Raman intensity (counts)')
            zlabel(app.UIAxesSmooth, 'Z')
            app.TruncationandZappingTab = uitab(app.TabGroup);
            app.TruncationandZappingTab.Title = 'Truncation and Zapping';
            app.UIAxesTrunc = uiaxes(app.TruncationandZappingTab);
            title(app.UIAxesTrunc, 'Truncation and Zapping')
            xlabel(app.UIAxesTrunc, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesTrunc, 'Raman Intensity (counts)')
            zlabel(app.UIAxesTrunc, 'Z')
            app.NormalisationTab = uitab(app.TabGroup);
            app.NormalisationTab.Title = 'Normalisation';
            app.UIAxesNormBefore = uiaxes(app.NormalisationTab);
            title(app.UIAxesNormBefore, 'Before')
            xlabel(app.UIAxesNormBefore, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesNormBefore, 'Raman intensity (counts)')
            zlabel(app.UIAxesNormBefore, 'Z')
            app.UIAxesNormAfter = uiaxes(app.NormalisationTab);
            title(app.UIAxesNormAfter, 'Normalisation')
            xlabel(app.UIAxesNormAfter, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesNormAfter, 'Raman intensity (normalised)')
            zlabel(app.UIAxesNormAfter, 'Z')
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.UIAxesVarianceExplained = uiaxes(app.PCATab);
            title(app.UIAxesVarianceExplained, 'PCA variance explained')
            xlabel(app.UIAxesVarianceExplained, 'Coefficient number')
            ylabel(app.UIAxesVarianceExplained, 'Cumsum Variance (%)')
            zlabel(app.UIAxesVarianceExplained, 'Z')
            app.UIAxesPCALoading = uiaxes(app.PCATab);
            title(app.UIAxesPCALoading, 'Principal Component Loading')
            xlabel(app.UIAxesPCALoading, 'Wavenumber (cm-1)')
            ylabel(app.UIAxesPCALoading, 'Intensity')
            zlabel(app.UIAxesPCALoading, 'Z')
            app.UIAxesPCAScores = uiaxes(app.PCATab);
            title(app.UIAxesPCAScores, 'Scores')
            xlabel(app.UIAxesPCAScores, 'X')
            ylabel(app.UIAxesPCAScores, 'Y')
            zlabel(app.UIAxesPCAScores, 'Z')
            app.UMAPTab = uitab(app.TabGroup);
            app.UMAPTab.Title = 'UMAP';
            app.UIAxesUMAP = uiaxes(app.UMAPTab);
            title(app.UIAxesUMAP, 'UMAP')
            xlabel(app.UIAxesUMAP, 'UMAP 1')
            ylabel(app.UIAxesUMAP, 'UMAP 2')
            zlabel(app.UIAxesUMAP, 'Z')
            app.LDATab = uitab(app.TabGroup);
            app.LDATab.Title = 'LDA';
            app.UIAxesLDAScores = uiaxes(app.LDATab);
            title(app.UIAxesLDAScores, 'LDA scores')
            xlabel(app.UIAxesLDAScores, 'X')
            ylabel(app.UIAxesLDAScores, 'Y')
            zlabel(app.UIAxesLDAScores, 'Z')
            app.UIAxesLDALoading = uiaxes(app.LDATab);
            title(app.UIAxesLDALoading, 'LDA Loading')
            xlabel(app.UIAxesLDALoading, 'X')
            ylabel(app.UIAxesLDALoading, 'Y')
            zlabel(app.UIAxesLDALoading, 'Z')
            app.LDAConfusionmatrixTab = uitab(app.TabGroup);
            app.LDAConfusionmatrixTab.Title = 'LDA Confusion matrix';
            app.ConfusionPanel = uipanel(app.LDAConfusionmatrixTab);
            app.ConfusionPanel.Title = 'Confusion Panel';
            app.Mode = uitabgroup(app.UIFigure);
            app.PreprocessingTab = uitab(app.Mode);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.LoadButton = uibutton(app.PreprocessingTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load data';
            app.ClassInfoLabel = uilabel(app.PreprocessingTab);
            app.ClassInfoLabel.Text = 'No class selected';
            app.MeasuredPeakLabel = uilabel(app.PreprocessingTab);
            app.BaselinesubtractionLabel = uilabel(app.PreprocessingTab);
            app.BaselinesubtractionLabel.Text = 'Baseline subtraction';
            app.SmoothingLabel = uilabel(app.PreprocessingTab);
            app.SmoothingLabel.Text = 'Smoothing';
            app.TruncatingandZappingLabel = uilabel(app.PreprocessingTab);
            app.TruncatingandZappingLabel.Text = 'Truncating and Zapping';
            app.EnableZappingCheckBox = uicheckbox(app.PreprocessingTab);
            app.EnableZappingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableZappingCheckBoxValueChanged, true);
            app.EnableZappingCheckBox.Text = 'Zapping?';
            app.EnableZappingCheckBox.Value = true;
            app.NormalisationLabel = uilabel(app.PreprocessingTab);
            app.NormalisationLabel.Text = 'Normalisation';
            app.ProcessAllButton = uibutton(app.PreprocessingTab, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process all spectra';
            app.Save = uibutton(app.PreprocessingTab, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save processed datasets';
            app.Progress = uilabel(app.PreprocessingTab);
            app.Progress.Text = '0% progress';
            app.ClassDropDownLabel = uilabel(app.PreprocessingTab);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.PreprocessingTab);
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.SpectrumNumberFieldLabel = uilabel(app.PreprocessingTab);
            app.SpectrumNumberFieldLabel.Text = 'Spectrum number';
            app.SpectrumNumberField = uieditfield(app.PreprocessingTab, 'numeric');
            app.SpectrumNumberField.Limits = [1 Inf];
            app.SpectrumNumberField.RoundFractionalValues = 'on';
            app.SpectrumNumberField.ValueDisplayFormat = '%11g';
            app.SpectrumNumberField.ValueChangedFcn = createCallbackFcn(app, @SpectrumNumberFieldValueChanged, true);
            app.SpectrumNumberField.Value = 1;
            app.Referencepeakcm1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Referencepeakcm1EditFieldLabel.Text = 'Reference peak (cm-1)';
            app.ReferencePeakField = uieditfield(app.PreprocessingTab, 'numeric');
            app.ReferencePeakField.Limits = [0 Inf];
            app.ReferencePeakField.ValueChangedFcn = createCallbackFcn(app, @ReferencePeakFieldValueChanged, true);
            app.ReferencePeakField.Value = 520.5;
            app.Smooth1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Smooth1EditFieldLabel.Text = 'Smooth 1';
            app.Smooth1Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Smooth1Field.Limits = [1 Inf];
            app.Smooth1Field.ValueChangedFcn = createCallbackFcn(app, @Smooth1FieldValueChanged, true);
            app.Smooth1Field.Value = 40;
            app.Bandwidth1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Bandwidth1EditFieldLabel.Text = 'Bandwidth 1';
            app.Bandwidth1Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Bandwidth1Field.Limits = [1 Inf];
            app.Bandwidth1Field.ValueChangedFcn = createCallbackFcn(app, @Bandwidth1FieldValueChanged, true);
            app.Bandwidth1Field.Value = 500;
            app.Iterations1EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Iterations1EditFieldLabel.Text = 'Iterations 1';
            app.Iterations1Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Iterations1Field.Limits = [1 1000];
            app.Iterations1Field.ValueChangedFcn = createCallbackFcn(app, @Iterations1FieldValueChanged, true);
            app.Iterations1Field.Value = 15;
            app.Smooth2EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Smooth2EditFieldLabel.Text = 'Smooth 2';
            app.Smooth2Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Smooth2Field.Limits = [1 Inf];
            app.Smooth2Field.ValueChangedFcn = createCallbackFcn(app, @Smooth2FieldValueChanged, true);
            app.Smooth2Field.Value = 60;
            app.Bandwidth2EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Bandwidth2EditFieldLabel.Text = 'Bandwidth 2';
            app.Bandwidth2Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Bandwidth2Field.ValueChangedFcn = createCallbackFcn(app, @Bandwidth2FieldValueChanged, true);
            app.Bandwidth2Field.Value = 800;
            app.Iterations2EditFieldLabel = uilabel(app.PreprocessingTab);
            app.Iterations2EditFieldLabel.Text = 'Iterations 2';
            app.Iterations2Field = uieditfield(app.PreprocessingTab, 'numeric');
            app.Iterations2Field.Limits = [0 1000];
            app.Iterations2Field.ValueChangedFcn = createCallbackFcn(app, @Iterations2FieldValueChanged, true);
            app.Iterations2Field.Value = 5;
            app.PolynomialorderEditFieldLabel = uilabel(app.PreprocessingTab);
            app.PolynomialorderEditFieldLabel.Text = 'Polynomial order';
            app.PolyOrderField = uieditfield(app.PreprocessingTab, 'numeric');
            app.PolyOrderField.Limits = [1 Inf];
            app.PolyOrderField.ValueChangedFcn = createCallbackFcn(app, @PolyOrderFieldValueChanged, true);
            app.PolyOrderField.Value = 2;
            app.WindowlengthEditFieldLabel = uilabel(app.PreprocessingTab);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowLengthField = uieditfield(app.PreprocessingTab, 'numeric');
            app.WindowLengthField.Limits = [1 Inf];
            app.WindowLengthField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthFieldValueChanged, true);
            app.WindowLengthField.Value = 55;
            app.StartwavenumberEditFieldLabel = uilabel(app.PreprocessingTab);
            app.StartwavenumberEditFieldLabel.Text = 'Start wavenumber';
            app.StartWavenumberField = uieditfield(app.PreprocessingTab, 'numeric');
            app.StartWavenumberField.Limits = [100 Inf];
            app.StartWavenumberField.ValueChangedFcn = createCallbackFcn(app, @StartWavenumberFieldValueChanged, true);
            app.StartWavenumberField.Value = 100;
            app.EndwavenumberFieldLabel = uilabel(app.PreprocessingTab);
            app.EndwavenumberFieldLabel.Text = 'End wavenumber';
            app.EndWavenumberField = uieditfield(app.PreprocessingTab, 'numeric');
            app.EndWavenumberField.Limits = [100 Inf];
            app.EndWavenumberField.ValueChangedFcn = createCallbackFcn(app, @EndWavenumberFieldValueChanged, true);
            app.EndWavenumberField.Value = 3150;
            app.ZapstartEditFieldLabel = uilabel(app.PreprocessingTab);
            app.ZapstartEditFieldLabel.Text = 'Zap start';
            app.ZapStartField = uieditfield(app.PreprocessingTab, 'numeric');
            app.ZapStartField.Limits = [100 Inf];
            app.ZapStartField.ValueChangedFcn = createCallbackFcn(app, @ZapStartFieldValueChanged, true);
            app.ZapStartField.Value = 1850;
            app.ZapendEditFieldLabel = uilabel(app.PreprocessingTab);
            app.ZapendEditFieldLabel.Text = 'Zap end';
            app.ZapEndField = uieditfield(app.PreprocessingTab, 'numeric');
            app.ZapEndField.Limits = [100 Inf];
            app.ZapEndField.ValueChangedFcn = createCallbackFcn(app, @ZapEndFieldValueChanged, true);
            app.ZapEndField.Value = 2650;
            app.DropDownLabel = uilabel(app.PreprocessingTab);
            app.DropDownLabel.Text = 'Normalisation method';
            app.NormalisationMethodDropDown = uidropdown(app.PreprocessingTab);
            app.NormalisationMethodDropDown.Items = {'Amide I peak', 'Total area', 'Selected peak'};
            app.NormalisationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalisationMethodDropDownValueChanged, true);
            app.NormalisationMethodDropDown.Value = 'Amide I peak';
            app.SelectedpeakEditFieldLabel = uilabel(app.PreprocessingTab);
            app.SelectedpeakEditFieldLabel.Visible = 'off';
            app.SelectedpeakEditFieldLabel.Text = 'Selected peak';
            app.SelectedPeakField = uieditfield(app.PreprocessingTab, 'numeric');
            app.SelectedPeakField.Limits = [1 Inf];
            app.SelectedPeakField.ValueChangedFcn = createCallbackFcn(app, @SelectedPeakFieldValueChanged, true);
            app.SelectedPeakField.Visible = 'off';
            app.SelectedPeakField.Value = 1660;
            app.ShowAllSpectraCheckBox = uicheckbox(app.PreprocessingTab);
            app.ShowAllSpectraCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAllSpectraCheckBoxValueChanged, true);
            app.ShowAllSpectraCheckBox.Visible = 'off';
            app.ShowAllSpectraCheckBox.Enable = 'off';
            app.ShowAllSpectraCheckBox.Text = 'Show all spectra';
            app.AnalysisTab = uitab(app.Mode);
            app.AnalysisTab.Title = 'Analysis';
            app.PCAsettingsLabel = uilabel(app.AnalysisTab);
            app.PCAsettingsLabel.Text = 'Principal Component Analysis (PCA)';
            app.VarianceExplainedFieldLabel = uilabel(app.AnalysisTab);
            app.VarianceExplainedFieldLabel.Text = 'Variance explained';
            app.PCAVarianceField = uieditfield(app.AnalysisTab, 'numeric');
            app.PCAVarianceField.Limits = [1 100];
            app.PCAVarianceField.RoundFractionalValues = 'on';
            app.PCAVarianceField.ValueDisplayFormat = '%11g';
            app.PCAVarianceField.Value = 95;
            app.LoadingDropDownLabel = uilabel(app.AnalysisTab);
            app.LoadingDropDownLabel.Text = 'Loading';
            app.PCALoadingDropDown = uidropdown(app.AnalysisTab);
            app.PCALoadingDropDown.ValueChangedFcn = createCallbackFcn(app, @PCALoadingDropDownValueChanged, true);
            app.ScoreXDropDownLabel = uilabel(app.AnalysisTab);
            app.ScoreXDropDownLabel.Text = 'Score X';
            app.ScoreXDropDown = uidropdown(app.AnalysisTab);
            app.ScoreXDropDown.ValueChangedFcn = createCallbackFcn(app, @ScoreXDropDownValueChanged, true);
            app.ScoreYDropDownLabel = uilabel(app.AnalysisTab);
            app.ScoreYDropDownLabel.Text = 'Score Y';
            app.ScoreYDropDown = uidropdown(app.AnalysisTab);
            app.ScoreYDropDown.ValueChangedFcn = createCallbackFcn(app, @ScoreYDropDownValueChanged, true);
            app.RunPCAButton = uibutton(app.AnalysisTab, 'push');
            app.RunPCAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCAButtonPushed, true);
            app.RunPCAButton.Text = 'Run PCA';
            app.LoadPreprocessedButton = uibutton(app.AnalysisTab, 'push');
            app.LoadPreprocessedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreprocessedButtonPushed, true);
            app.LoadPreprocessedButton.Text = 'Load pre-processed data (optional)';
            app.LDALabel = uilabel(app.AnalysisTab);
            app.LDALabel.Text = 'Linear Discriminant Analysis (LDA)';
            app.UnsupervisedLabel = uilabel(app.AnalysisTab);
            app.UnsupervisedLabel.Text = 'UNSUPERVISED MULTIVARIATE METHODS';
            app.UMAPLabel = uilabel(app.AnalysisTab);
            app.UMAPLabel.Text = 'Uniform Manifold Approximation and Projection (UMAP)';
            app.NumberOfNeighboursFieldLabel = uilabel(app.AnalysisTab);
            app.NumberOfNeighboursFieldLabel.Text = 'Number of neighbours';
            app.NumberOfNeighboursField = uieditfield(app.AnalysisTab, 'numeric');
            app.NumberOfNeighboursField.Limits = [2 Inf];
            app.NumberOfNeighboursField.RoundFractionalValues = 'on';
            app.NumberOfNeighboursField.Value = 15;
            app.DistancemetricDropDownLabel = uilabel(app.AnalysisTab);
            app.DistancemetricDropDownLabel.Text = 'Distance  metric';
            app.DistancemetricDropDown = uidropdown(app.AnalysisTab);
            app.DistancemetricDropDown.Items = {'euclidean', 'cosine', 'correlation'};
            app.DistancemetricDropDown.Value = 'euclidean';
            app.RunUMAPButton = uibutton(app.AnalysisTab, 'push');
            app.RunUMAPButton.ButtonPushedFcn = createCallbackFcn(app, @RunUMAPButtonPushed, true);
            app.RunUMAPButton.Text = 'Run UMAP';
            app.RunPCAUMAPButton = uibutton(app.AnalysisTab, 'push');
            app.RunPCAUMAPButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCAUMAPButtonPushed, true);
            app.RunPCAUMAPButton.Text = 'Run PCA + UMAP';
            app.SupervisedLabel = uilabel(app.AnalysisTab);
            app.SupervisedLabel.Text = 'SUPERVISED MULTIVARIATE METHODS';
            app.kvalueforkfoldLabel = uilabel(app.AnalysisTab);
            app.kvalueforkfoldLabel.Text = 'k value for k-fold';
            app.KFoldField = uieditfield(app.AnalysisTab, 'numeric');
            app.KFoldField.Limits = [2 20];
            app.KFoldField.RoundFractionalValues = 'on';
            app.KFoldField.Value = 5;
            app.RandomseedEditFieldLabel = uilabel(app.AnalysisTab);
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.RandomSeedField = uieditfield(app.AnalysisTab, 'numeric');
            app.RandomSeedField.Limits = [0 Inf];
            app.RandomSeedField.RoundFractionalValues = 'on';
            app.RandomSeedField.Tooltip = {'For reproducible splits between runs use the same seed (integrer between 1 and infinity).'};
            app.RandomSeedField.Value = 1;
            app.RunPCALDAButton = uibutton(app.AnalysisTab, 'push');
            app.RunPCALDAButton.ButtonPushedFcn = createCallbackFcn(app, @RunPCALDAButtonPushed, true);
            app.RunPCALDAButton.Text = 'Run PCA + LDA';
            app.LDALoadingDropDownLabel = uilabel(app.AnalysisTab);
            app.LDALoadingDropDownLabel.Visible = 'off';
            app.LDALoadingDropDownLabel.Text = 'LDA Loading';
            app.LDALoadingDropDown = uidropdown(app.AnalysisTab);
            app.LDALoadingDropDown.ValueChangedFcn = createCallbackFcn(app, @LDALoadingDropDownValueChanged, true);
            app.LDALoadingDropDown.Visible = 'off';
            app.ConfusionmatrixdropdownLabel = uilabel(app.AnalysisTab);
            app.ConfusionmatrixdropdownLabel.Visible = 'off';
            app.ConfusionmatrixdropdownLabel.Text = 'Confusion matrix dropdown';
            app.ConfusionMatrixDropDown = uidropdown(app.AnalysisTab);
            app.ConfusionMatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfusionMatrixDropDownValueChanged, true);
            app.ConfusionMatrixDropDown.Visible = 'off';
            app.LDAAccuracyLabel = uilabel(app.AnalysisTab);
            app.LDAAccuracyLabel.Visible = 'off';
            app.LDAAccuracyLabel.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RamanPreprocessing_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end